classdef SRP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        Channel1LampLabel         matlab.ui.control.Label
        Channel1Lamp              matlab.ui.control.Lamp
        Channel2LampLabel         matlab.ui.control.Label
        Channel2Lamp              matlab.ui.control.Lamp
        MainPanel                 matlab.ui.container.Panel
        LodingButton              matlab.ui.control.Button
        RecallButton              matlab.ui.control.Button
        ChannelButtonGroup        matlab.ui.container.ButtonGroup
        Channel1Button            matlab.ui.control.RadioButton
        Channel2Button            matlab.ui.control.RadioButton
        DonwPassButton            matlab.ui.control.Button
        UpPassButton              matlab.ui.control.Button
        NoiseToolPanel            matlab.ui.container.Panel
        TypeDropDownLabel         matlab.ui.control.Label
        TypeDropDown              matlab.ui.control.DropDown
        VarDenEditFieldLabel      matlab.ui.control.Label
        VarDenEditField           matlab.ui.control.NumericEditField
        STARTButton_2             matlab.ui.control.Button
        DisplayPanel_2            matlab.ui.container.Panel
        ResultEditFieldLabel      matlab.ui.control.Label
        ResultEditField           matlab.ui.control.EditField
        SimilarityEditFieldLabel  matlab.ui.control.Label
        SimilarityEditField       matlab.ui.control.NumericEditField
        ChoiceDropDownLabel       matlab.ui.control.Label
        ChoiceDropDown            matlab.ui.control.DropDown
        ImageInformationsPanel    matlab.ui.container.Panel
        Channel1EditFieldLabel    matlab.ui.control.Label
        Channel1EditField         matlab.ui.control.EditField
        Channel2EditFieldLabel    matlab.ui.control.Label
        Channel2EditField         matlab.ui.control.EditField
        GOGOGOPanel               matlab.ui.container.Panel
        MDropDownLabel            matlab.ui.control.Label
        MDropDown                 matlab.ui.control.DropDown
        KDropDownLabel            matlab.ui.control.Label
        KDropDown                 matlab.ui.control.DropDown
        STARTButton               matlab.ui.control.Button
        ThresholdEditFieldLabel   matlab.ui.control.Label
        ThresholdEditField        matlab.ui.control.NumericEditField
        IMPROVEDMODELButton       matlab.ui.control.StateButton
        TimeFilterToolPanel       matlab.ui.container.Panel
        SizeDropDownLabel         matlab.ui.control.Label
        SizeDropDown              matlab.ui.control.DropDown
        AverageButton             matlab.ui.control.Button
        MidValueButton            matlab.ui.control.Button
        MosaicButton              matlab.ui.control.Button
        FrequencyFilterToolPanel  matlab.ui.container.Panel
        GSLButton                 matlab.ui.control.Button
        GSHButton                 matlab.ui.control.Button
        DEditFieldLabel           matlab.ui.control.Label
        DEditField                matlab.ui.control.NumericEditField
        SpecialToolPanel          matlab.ui.container.Panel
        DSLBPButton               matlab.ui.control.Button
        RotationButton            matlab.ui.control.Button
        CropButton                matlab.ui.control.Button
        MirrorButton              matlab.ui.control.Button
        Switch                    matlab.ui.control.RockerSwitch
        GrayProcessingToolPanel   matlab.ui.container.Panel
        HisEqlButton              matlab.ui.control.Button
        LapEnHButton              matlab.ui.control.Button
        SobelButton               matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        BrightnessLabel           matlab.ui.control.Label
        DilateButton              matlab.ui.control.Button
        ErodeButton               matlab.ui.control.Button
        SeSizeDropDownLabel       matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        SeSizeDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        images1; % Description
        images2;
        Imagenumber1=0;
        Imagenumber2=0;
        PresentChannel=1;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            axis(app.UIAxes_2,'off');
          
        end

        % Button pushed function: LodingButton
        function LodingButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.jpg';'*.png';'*.tif';'*.jpeg'});
          if filename==0
              warndlg('请选择图片','警告');                  
              return
          end
           %首先将上一次读取的图片及其处理全部清空
           switch app.PresentChannel
               case 1
                   app.images1(:)=[];
                   app.Imagenumber1=0;
                   image=imread([pathname,filename]);
                   app.Imagenumber1=app.Imagenumber1+1;
                   app.images1{app.Imagenumber1}=image;
                   imshow(image,'parent',app.UIAxes);
                   [row,col,ox]=size(image);
                   str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
                   app.Channel1EditField.Value=str;
               case 2
                   app.images2(:)=[];
                   app.Imagenumber2=0;
                   image=imread([pathname,filename]);
                   app.Imagenumber2=app.Imagenumber1+2;
                   app.images2{app.Imagenumber2}=image;
                   imshow(image,'parent',app.UIAxes_2);
                   [row,col,ox]=size(image);
                   str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
                   app.Channel2EditField.Value=str;
          end            
        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
            if app.Channel1Button.Value==true
                presentChannel=1;
                app.Channel1Lamp.Color='green';
                app.Channel2Lamp.Color='white';
            elseif app.Channel2Button.Value==true
                presentChannel=2;  
                app.Channel2Lamp.Color='green';
                app.Channel1Lamp.Color='white';
            end
            app.PresentChannel=presentChannel; 
        end

        % Button pushed function: RecallButton
        function RecallButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    if app.Imagenumber1==1
                        warndlg('已经是最后一张图片辣');
                        return
                    end
                    %撤销的算法就是删除当前图片，并显示回原来的图片
                    app.Imagenumber1=app.Imagenumber1-1;
                    image=cell2mat(app.images1(app.Imagenumber1));
                    imshow(image,'parent',app.UIAxes)
                case 2
                    if app.Imagenumber2==1
                        warndlg('已经是最后一张图片辣');
                        return
                    end
                    %撤销的算法就是删除当前图片，并显示回原来的图片
                    app.Imagenumber2=app.Imagenumber2-1;
                    image=cell2mat(app.images2(app.Imagenumber2));
                    imshow(image,'parent',app.UIAxes_2)           
            end    
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            Im1=cell2mat(app.images1(app.Imagenumber1));
            Im2=cell2mat(app.images2(app.Imagenumber2));
            M=str2double(app.MDropDown.Value);
            K=str2double(app.KDropDown.Value);
            choice=app.ChoiceDropDown.Value;
            if app.IMPROVEDMODELButton.Value==true
                choice='improved';
            end
            Threshold=app.ThresholdEditField.Value;
            Hamming=SRP_Test_simple(Im1,Im2,256,M,K,choice);
            switch choice
                case 'ALL'
                    app.SimilarityEditField.Value=1-Hamming/256; 
                case 'LOCAL'
                    app.SimilarityEditField.Value=1-Hamming/64; 
                case 'GLOBAL'
                    app.SimilarityEditField.Value=1-Hamming/192;
                case 'improved'
                    app.SimilarityEditField.Value=1-Hamming/192;
            end
          
            if Hamming>Threshold
                app.ResultEditField.Value="Not a Copied one";
            else
                app.ResultEditField.Value="Copied one";
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    switch app.TypeDropDown.Value
                        case 'Gaussian'
                            Im_pro=imnoise(Im,'Gaussian',0,app.VarDenEditField.Value);
                        case 'S & P'
                            Im_pro=imnoise(Im,'salt & pepper',app.VarDenEditField.Value);
                        case 'Speckle'
                           Im_pro=imnoise(Im,'speckle',app.VarDenEditField.Value);                           
                    end
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    switch app.TypeDropDown.Value
                        case 'Gaussian'
                            Im_pro=imnoise(Im,'gaussian',0,app.VarDenEditField.Value);
                        case 'S & P'
                            Im_pro=imnoise(Im,'salt & pepper',app.VarDenEditField.Value);
                        case 'Speckle'
                           Im_pro=imnoise(Im,'speckle',app.VarDenEditField.Value);                           
                    end
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);
            end
        end

        % Button pushed function: DSLBPButton
        function DSLBPButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=CSLBP(Im);
                    Im_pro=Im_pro./(15*ones(size(Im_pro)));               
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=CSLBP(Im);
                    Im_pro=Im_pro./(15*ones(size(Im_pro)));  
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);
            end
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            size=str2double(app.SizeDropDown.Value(1));
             switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_filter=fspecial('average',[size,size]);
                    Im_pro=imfilter(Im,Im_filter);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_filter=fspecial('average',[size,size]);
                    Im_pro=imfilter(Im,Im_filter);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
             end
        end

        % Button pushed function: MidValueButton
        function MidValueButtonPushed(app, event)
             size=str2double(app.SizeDropDown.Value(1));
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=MidvalueFilter(Im,size);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=MidvalueFilter(Im,size);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: MosaicButton
        function MosaicButtonPushed(app, event)
             size=str2double(app.SizeDropDown.Value(1));
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=Mosaic(Im,size);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=Mosaic(Im,size);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: HisEqlButton
        function HisEqlButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=histeq(Im);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=histeq(Im);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: DonwPassButton
        function DonwPassButtonPushed(app, event)
            Im=cell2mat(app.images1(app.Imagenumber1));
            app.Imagenumber2=app.Imagenumber2+1;
            app.images2{app.Imagenumber2}=Im;
            imshow(Im,'parent',app.UIAxes_2);
            [row,col,ox]=size(Im);
            str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
            app.Channel2EditField.Value=str;
          
        end

        % Button pushed function: UpPassButton
        function UpPassButtonPushed(app, event)
            Im=cell2mat(app.images2(app.Imagenumber2));
            app.Imagenumber1=app.Imagenumber1+1;
            app.images1{app.Imagenumber1}=Im;
            imshow(Im,'parent',app.UIAxes);
            [row,col,ox]=size(Im);
            str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
            app.Channel1EditField.Value=str;
          
        end

        % Button pushed function: LapEnHButton
        function LapEnHButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=LaplaceEnhence(Im);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=LaplaceEnhence(Im);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=SobelEnhence(Im,'crossway');
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                     Im_pro=SobelEnhence(Im,'crossway');
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=GrayEnhencement(Im,value);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=GrayEnhencement(Im,value);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
         
        end

        % Button pushed function: GSLButton
        function GSLButtonPushed(app, event)
            D=app.DEditField.Value;
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=LPFOnlyforRGB(Im,'gaussian',D);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=LPFOnlyforRGB(Im,'gaussian',D);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: GSHButton
        function GSHButtonPushed(app, event)
            D=app.DEditField.Value;
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=HPFOnlyforRGB(Im,'gaussian',D);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=HPFOnlyforRGB(Im,'gaussian',D);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            Sesize=str2double(app.SeSizeDropDown.Value(1));
            se=strel('square',Sesize);
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=imerode(Im,se);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=imerode(Im,se);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            Sesize=str2double(app.SeSizeDropDown.Value(1));
            se=strel('square',Sesize);
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=imdilate(Im,se);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=imdilate(Im,se);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
             switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=imrotate(Im,90);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                     Im_pro=imrotate(Im,90);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=imcrop(Im);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                    [row,col,ox]=size(Im_pro);
                    str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
                    app.Channel1EditField.Value=str;
          
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=imcrop(Im);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2); 
                    [row,col,ox]=size(Im_pro);
                    str=strcat(num2str(row),'×',num2str(col),'x',num2str(ox));
                    app.Channel1EditField.Value=str;       
            end
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            switch app.Switch.Value
                case 'Horizontal'
                    a=1;
                case 'Vertical'
                    a=2;
            end
            switch app.PresentChannel
                case 1
                    Im=cell2mat(app.images1(app.Imagenumber1));
                    Im_pro=flip(Im,a);
                    app.Imagenumber1=app.Imagenumber1+1;
                    app.images1{app.Imagenumber1}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes);
                 case 2
                    Im=cell2mat(app.images2(app.Imagenumber2));
                    Im_pro=flip(Im,a);
                    app.Imagenumber2=app.Imagenumber2+1;
                    app.images2{app.Imagenumber2}=Im_pro;
                    imshow(Im_pro,'parent',app.UIAxes_2);    
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.MDropDown.Value=5;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Channel1LampLabel = uilabel(app.UIFigure);
            app.Channel1LampLabel.Text = 'Channel1';
            app.Channel1Lamp = uilamp(app.UIFigure);
            app.Channel2LampLabel = uilabel(app.UIFigure);
            app.Channel2LampLabel.Text = 'Channel2';
            app.Channel2Lamp = uilamp(app.UIFigure);
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Main';
            app.LodingButton = uibutton(app.MainPanel, 'push');
            app.LodingButton.ButtonPushedFcn = createCallbackFcn(app, @LodingButtonPushed, true);
            app.LodingButton.Text = 'Loding';
            app.RecallButton = uibutton(app.MainPanel, 'push');
            app.RecallButton.ButtonPushedFcn = createCallbackFcn(app, @RecallButtonPushed, true);
            app.RecallButton.Text = 'Recall';
            app.ChannelButtonGroup = uibuttongroup(app.MainPanel);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.Channel1Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel1Button.Text = 'Channel1';
            app.Channel1Button.Value = true;
            app.Channel2Button = uiradiobutton(app.ChannelButtonGroup);
            app.Channel2Button.Text = 'Channel2';
            app.DonwPassButton = uibutton(app.MainPanel, 'push');
            app.DonwPassButton.ButtonPushedFcn = createCallbackFcn(app, @DonwPassButtonPushed, true);
            app.DonwPassButton.Text = 'Donw Pass';
            app.UpPassButton = uibutton(app.MainPanel, 'push');
            app.UpPassButton.ButtonPushedFcn = createCallbackFcn(app, @UpPassButtonPushed, true);
            app.UpPassButton.Text = 'Up Pass';
            app.NoiseToolPanel = uipanel(app.UIFigure);
            app.NoiseToolPanel.Title = 'Noise Tool';
            app.TypeDropDownLabel = uilabel(app.NoiseToolPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.NoiseToolPanel);
            app.TypeDropDown.Items = {'Gaussian', 'S & P', 'Speckle'};
            app.TypeDropDown.Value = 'Gaussian';
            app.VarDenEditFieldLabel = uilabel(app.NoiseToolPanel);
            app.VarDenEditFieldLabel.Text = 'Var/Den';
            app.VarDenEditField = uieditfield(app.NoiseToolPanel, 'numeric');
            app.VarDenEditField.Value = 0.01;
            app.STARTButton_2 = uibutton(app.NoiseToolPanel, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.DisplayPanel_2 = uipanel(app.UIFigure);
            app.DisplayPanel_2.Title = 'Display';
            app.ResultEditFieldLabel = uilabel(app.DisplayPanel_2);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.DisplayPanel_2, 'text');
            app.ResultEditField.Editable = 'off';
            app.SimilarityEditFieldLabel = uilabel(app.DisplayPanel_2);
            app.SimilarityEditFieldLabel.Text = 'Similarity';
            app.SimilarityEditField = uieditfield(app.DisplayPanel_2, 'numeric');
            app.SimilarityEditField.Editable = 'off';
            app.ChoiceDropDownLabel = uilabel(app.DisplayPanel_2);
            app.ChoiceDropDownLabel.Text = 'Choice';
            app.ChoiceDropDown = uidropdown(app.DisplayPanel_2);
            app.ChoiceDropDown.Items = {'ALL', 'LOCAL', 'GLOBAL'};
            app.ChoiceDropDown.Value = 'ALL';
            app.ImageInformationsPanel = uipanel(app.UIFigure);
            app.ImageInformationsPanel.Title = 'Image Informations';
            app.Channel1EditFieldLabel = uilabel(app.ImageInformationsPanel);
            app.Channel1EditFieldLabel.Text = 'Channel1';
            app.Channel1EditField = uieditfield(app.ImageInformationsPanel, 'text');
            app.Channel1EditField.Editable = 'off';
            app.Channel2EditFieldLabel = uilabel(app.ImageInformationsPanel);
            app.Channel2EditFieldLabel.Text = 'Channel2';
            app.Channel2EditField = uieditfield(app.ImageInformationsPanel, 'text');
            app.Channel2EditField.Editable = 'off';
            app.GOGOGOPanel = uipanel(app.UIFigure);
            app.GOGOGOPanel.Title = 'GOGOGO';
            app.MDropDownLabel = uilabel(app.GOGOGOPanel);
            app.MDropDownLabel.Text = 'M';
            app.MDropDown = uidropdown(app.GOGOGOPanel);
            app.MDropDown.Items = {'4', '8', '16'};
            app.MDropDown.Value = '8';
            app.KDropDownLabel = uilabel(app.GOGOGOPanel);
            app.KDropDownLabel.Text = 'K';
            app.KDropDown = uidropdown(app.GOGOGOPanel);
            app.KDropDown.Items = {'32', '64', '128', '256'};
            app.KDropDown.Value = '64';
            app.STARTButton = uibutton(app.GOGOGOPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ThresholdEditFieldLabel = uilabel(app.GOGOGOPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GOGOGOPanel, 'numeric');
            app.ThresholdEditField.Value = 68;
            app.IMPROVEDMODELButton = uibutton(app.GOGOGOPanel, 'state');
            app.IMPROVEDMODELButton.Text = 'IMPROVED MODEL';
            app.TimeFilterToolPanel = uipanel(app.UIFigure);
            app.TimeFilterToolPanel.Title = 'Time Filter Tool';
            app.SizeDropDownLabel = uilabel(app.TimeFilterToolPanel);
            app.SizeDropDownLabel.Text = 'Size';
            app.SizeDropDown = uidropdown(app.TimeFilterToolPanel);
            app.SizeDropDown.Items = {'3X3', '5X5', '7X7', '9X9', '2', '4', '6', '8', '16'};
            app.SizeDropDown.Value = '3X3';
            app.AverageButton = uibutton(app.TimeFilterToolPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.MidValueButton = uibutton(app.TimeFilterToolPanel, 'push');
            app.MidValueButton.ButtonPushedFcn = createCallbackFcn(app, @MidValueButtonPushed, true);
            app.MidValueButton.Text = 'MidValue';
            app.MosaicButton = uibutton(app.TimeFilterToolPanel, 'push');
            app.MosaicButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicButtonPushed, true);
            app.MosaicButton.Text = 'Mosaic';
            app.FrequencyFilterToolPanel = uipanel(app.UIFigure);
            app.FrequencyFilterToolPanel.Title = 'Frequency Filter Tool';
            app.GSLButton = uibutton(app.FrequencyFilterToolPanel, 'push');
            app.GSLButton.ButtonPushedFcn = createCallbackFcn(app, @GSLButtonPushed, true);
            app.GSLButton.Text = 'GSL';
            app.GSHButton = uibutton(app.FrequencyFilterToolPanel, 'push');
            app.GSHButton.ButtonPushedFcn = createCallbackFcn(app, @GSHButtonPushed, true);
            app.GSHButton.Text = 'GSH';
            app.DEditFieldLabel = uilabel(app.FrequencyFilterToolPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.FrequencyFilterToolPanel, 'numeric');
            app.DEditField.Value = 25;
            app.SpecialToolPanel = uipanel(app.UIFigure);
            app.SpecialToolPanel.Title = 'Special Tool';
            app.DSLBPButton = uibutton(app.SpecialToolPanel, 'push');
            app.DSLBPButton.ButtonPushedFcn = createCallbackFcn(app, @DSLBPButtonPushed, true);
            app.DSLBPButton.Text = 'DSLBP';
            app.RotationButton = uibutton(app.SpecialToolPanel, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.CropButton = uibutton(app.SpecialToolPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.MirrorButton = uibutton(app.SpecialToolPanel, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Text = 'Mirror';
            app.Switch = uiswitch(app.SpecialToolPanel, 'rocker');
            app.Switch.Items = {'Vertical', 'Horizontal'};
            app.Switch.Value = 'Vertical';
            app.GrayProcessingToolPanel = uipanel(app.UIFigure);
            app.GrayProcessingToolPanel.Title = 'Gray Processing Tool';
            app.HisEqlButton = uibutton(app.GrayProcessingToolPanel, 'push');
            app.HisEqlButton.ButtonPushedFcn = createCallbackFcn(app, @HisEqlButtonPushed, true);
            app.HisEqlButton.Text = 'HisEql';
            app.LapEnHButton = uibutton(app.GrayProcessingToolPanel, 'push');
            app.LapEnHButton.ButtonPushedFcn = createCallbackFcn(app, @LapEnHButtonPushed, true);
            app.LapEnHButton.Text = 'LapEnH';
            app.SobelButton = uibutton(app.GrayProcessingToolPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.Slider = uislider(app.GrayProcessingToolPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.BrightnessLabel = uilabel(app.GrayProcessingToolPanel);
            app.BrightnessLabel.Text = 'Brightness';
            app.DilateButton = uibutton(app.GrayProcessingToolPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.ErodeButton = uibutton(app.GrayProcessingToolPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.SeSizeDropDownLabel = uilabel(app.GrayProcessingToolPanel);
            app.SeSizeDropDownLabel.Text = 'SeSize';
            app.SeSizeDropDown = uidropdown(app.GrayProcessingToolPanel);
            app.SeSizeDropDown.Items = {'3X3', '5X5', '7X7', '10X10'};
            app.SeSizeDropDown.Value = '3X3';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SRP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end